classdef GeoTiff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        CheckBox       matlab.ui.control.CheckBox
        Search         matlab.ui.control.Button
        GeoTIFFButton  matlab.ui.control.Button
        EditField      matlab.ui.control.EditField
        Label          matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end


    methods (Access = private)


    end

    methods (Access = public)
        function [] = MyGeoTIFFConvert(app,file_name)
            % 参考：https://www.gis-py.com/entry/2016/01/10/163027
            % 出力用に名前を保存
            [fpath,name,~] = fileparts(file_name);
            name = string(strsplit(name,'-'));
            name = strjoin(name(3:5),'');

            % 緯度経度:これをもとにGeoTiffに変換
            T = readstruct(file_name,'FileType','xml');
            T = T.DEM.coverage;
            LatLon  = struct2array(T.gml_boundedBy.gml_Envelope)';
            LatLon  = LatLon(2:end);
            LatLon  = double(split(LatLon))';

            % DataBlock:各グリッドセルの標高データ
            Raster  = splitlines(T.gml_rangeSet.gml_DataBlock.gml_tupleList);
            Raster  = str2double(extractAfter(Raster,','));

            % GridEnvelope:メッシュを構成するグリッドセルの配列数
            GridEnvelope = struct2array(T.gml_gridDomain.gml_Grid.gml_limits.gml_GridEnvelope)';
            GridEnvelope = double(split(GridEnvelope));

            % startPoint:グリッドセルの開始点を指定
            StartPoint   = str2double(strsplit(T.gml_coverageFunction.gml_GridFunction.gml_startPoint))+1;

            % サイズ
            RasterSize   = diff(GridEnvelope) + 1;
            RasterLength = prod(RasterSize);

            % startPointが0スタートでない場合、NaNで埋める
            % NaNは標高-9999mで指定⇒MATLABはNaNがいいらしい
            if length(Raster) == RasterLength
                idx = isnan(Raster);
                % Raster(idx) = -9999;
                Raster(idx) = NaN;
                RasterMap = reshape(Raster,RasterSize)';
            else
                StartPoint = prod(StartPoint);
                tmp = nan(RasterLength,1);
                tmp(StartPoint:StartPoint+length(Raster)-1) = Raster;
                idx = isnan(tmp);
                % tmp(idx) = -9999;
                tmp(idx) = NaN;
                RasterMap = reshape(tmp,RasterSize)';
            end

            % GeoTIFFファイルとして書き出し
            R = georefcells(LatLon(1,:), LatLon(2,:), flip(RasterSize),"ColumnsStartFrom","north");
            geotiffwrite(fullfile(fpath,'tmp',strjoin([name,'.tif'],'')),single(RasterMap),R,"CoordRefSysCode",'EPSG:6668')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Search
        function SearchButtonPushed(app, event)
            try
                app.EditField.Value = uigetdir(pwd,'フォルダの場所を検索');
            catch
                msg = "フォルダを選んでください";
                msgbox(msg)
                error(msg)
            end
        end

        % Button pushed function: GeoTIFFButton
        function GeoTIFFButtonPushed(app, event)
            % GeoTiff変換
            f = waitbar(0,'GeoTiff変換中');
            path = app.EditField.Value;
            if ~exist(fullfile(path,'tmp'),'dir')
                mkdir(path, 'tmp')
            end
            fnames = dir(fullfile(path,"*.xml"));
            path = fnames(1).folder;
            file = {fnames.name}';

            fsize =  length(file);

            for ii = 1:fsize
                waitbar(ii/fsize,f,'GeoTiff変換中')
                file_name = fullfile(path,file{ii});
                try
                    MyGeoTIFFConvert(app,file_name);
                catch
                    close(f)
                    rmdir(fullfile(path,'tmp'),'s')
                    msg = "正しくない名前のxmlファイルがあります";
                    msgbox(msg)
                    error(msg)
                end
            end
            close(f)


            % モザイク結合
            result = 'merge.tif';
            if exist(fullfile(path,result),'file')
                delete(fullfile(path,result))
            end
            fnames = dir(fullfile(path,"tmp","*.tif"));
            path = fnames(1).folder;
            file = {fnames.name}';
            fsize =  length(file);

            [~,name,~] = fileparts(file);
            try
                name = cell2mat(name);
            catch
                msg = "名前が数字以外のtiffファイルがあります";
                error(msg)
            end
            rr  = str2double(string(name(:,end-1:end)));

            IDX1 = false(10);
            IDX2 = IDX1;

            digits = [floor(rr./10), rem(rr,10)] + 1;
            Rasters = cell(10);
            IDX1(digits(:,1), digits(:,2)) = true;
            idx1 = sub2ind(size(IDX1),digits(:,1),digits(:,2));
            IDX2(idx1) = true;

            Lats = zeros(fsize,2);
            Lons = Lats;
            RSzs = Lats;

            f=waitbar(0,'モザイク結合中');
            for ii = 1:length(idx1)
                waitbar(ii/length(idx1),f,'モザイク結合中')
                [Z,Ref] = readgeoraster(fullfile(path,file{ii}),'OutputType','double');
                Rasters(idx1(ii)) = {Z};
                Lats(ii,:) = Ref.LatitudeLimits;
                Lons(ii,:) = Ref.LongitudeLimits;
                RSzs(ii,:) = Ref.RasterSize;
            end
            close(f)

            IDX3 = xor(IDX1,IDX2);
            idx2 = find(IDX3);
            Rasters(idx2) = {ones(RSzs(1,:)).*-9999};
            idx3 = find(~IDX1);
            Rasters(idx3) = {ones(RSzs(1,:)).*-9999};

            Rasters = cell2mat(flip(Rasters));
            Rasters(Rasters<-1000) = NaN;

            Rasters = Rasters(:,~all(isnan(Rasters),1));
            Rasters = Rasters(~all(isnan(Rasters),2),:);

            LatsLim = [min(Lats(:,1)), max(Lats(:,2))];
            LonsLim = [min(Lons(:,1)), max(Lons(:,2))];

            % 2023.11.01 NaNを補間 2023aからの新機能
            
            if app.CheckBox.Value
                f=waitbar(0,'欠損値埋め中');
                Rasters = fillmissing2(Rasters,'nearest');
                waitbar(1,f,'欠損値埋め中')
                close(f)
            end


            R = georefcells(LatsLim, LonsLim,size(Rasters),"ColumnsStartFrom","north");

            geoshow(app.UIAxes,uint8(Rasters),R)

            geotag = struct('GTModelTypeGeoKey', 2, 'GTRasterTypeGeoKey', 1, 'GeographicTypeGeoKey', ...
                6668,'GeogCitationGeoKey', 'JGD2011','GeogAngularUnitsGeoKey', 9102, 'GeogSemiMajorAxisGeoKey', ...
                6378137,'GeogInvFlatteningGeoKey', 298.2572);
            % geotiffwrite(fullfile(path,"..",result),round(single(Rasters),3),R,"CoordRefSysCode",'EPSG:6668','GeoKeyDirectoryTag',geotag)
            geotiffwrite(fullfile(path,"..",result),double(Rasters),R,"CoordRefSysCode",'EPSG:6668','GeoKeyDirectoryTag',geotag)

            rmdir(fullfile(path),'s')
            f = msgbox("GeoTIFF変換終了");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ラスタ画像')
            xlabel(app.UIAxes, '経度')
            ylabel(app.UIAxes, '緯度')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'フォルダ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.GeoTIFFButton = uibutton(app.UIFigure, 'push');
            app.GeoTIFFButton.ButtonPushedFcn = createCallbackFcn(app, @GeoTIFFButtonPushed, true);
            app.GeoTIFFButton.Text = 'GeoTIFF変換';
            app.Search = uibutton(app.UIFigure, 'push');
            app.Search.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.Search.Text = '検索';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '補間';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeoTiff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end